classdef simpleDisplay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        DoubleVerificationListBox    matlab.ui.control.ListBox
        DoubleVerificationLabel      matlab.ui.control.Label
        ModelListDropDown            matlab.ui.control.DropDown
        ModelListDropDownLabel       matlab.ui.control.Label
        StartDetectionButton         matlab.ui.control.Button
        SamplingRateperseccondEditField  matlab.ui.control.NumericEditField
        SamplingRateperseccondLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        signals = table(Size=[10 3], VariableTypes={'double','double','double'});
        samplingRate = 1;
        modelName = '1';
        options = detectOptions();
        detector = blinkDetector();
        blinkLocate = [];

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, blinkDetectorObject)
            
            app.signals = blinkDetectorObject.signals;
            app.samplingRate = blinkDetectorObject.samplingRate;
            app.modelName = blinkDetectorObject.modelName;
            app.options = blinkDetectorObject.options;
            
            app.detector = blinkDetectorObject();
            plot(app.UIAxes,app.signals{:,1});
            title(app.UIAxes,'dataset name')
            app.SamplingRateperseccondEditField.Value = app.samplingRate;
            app.Label.Text = 'dataset name';
        end

        % Button pushed function: StartDetectionButton
        function StartDetectionButtonPushed(app, event)
            app.detector.options = app.options;
            [varargout{1:nargout}] = app.detector.detectBlinks();
            blinkLocations = varargout{1};
            % TODO: progress bar
            stChannel = app.signals{:,1};
            plot(app.UIAxes,stChannel);

            coordinate = stChannel(blinkLocations);
            
            hold(app.UIAxes,'on');
            plot(app.UIAxes,1:size(stChannel),stChannel');
            plot(app.UIAxes,blinkLocations,coordinate,'r*');
            hold(app.UIAxes,'off');

            try
            catch
            end
        end

        % Value changed function: DoubleVerificationListBox
        function DoubleVerificationListBoxValueChanged(app, event)

            app.options.shiftFactor = app.DoubleVerificationListBox.Value;
        end

        % Value changed function: SamplingRateperseccondEditField
        function SamplingRateperseccondEditFieldValueChanged(app, event)
            app.samplingRate = app.SamplingRateperseccondEditField.Value;
            
        end

        % Value changed function: ModelListDropDown
        function ModelListDropDownValueChanged(app, event)
            app.modelName = app.ModelListDropDown.Value;
            
        end

        % Callback function: not associated with a component
        function UIAxesButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.SamplingRateperseccondLabel = uilabel(app.LeftPanel);
            app.SamplingRateperseccondLabel.Text = {'Sampling Rate'; '(per seccond)'};
            app.SamplingRateperseccondEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplingRateperseccondEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateperseccondEditFieldValueChanged, true);
            app.StartDetectionButton = uibutton(app.LeftPanel, 'push');
            app.StartDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartDetectionButtonPushed, true);
            app.StartDetectionButton.Text = 'Start Detection';
            app.ModelListDropDownLabel = uilabel(app.LeftPanel);
            app.ModelListDropDownLabel.Text = 'Model List';
            app.ModelListDropDown = uidropdown(app.LeftPanel);
            app.ModelListDropDown.Items = {'''TCNOC''', '''1d-CNNOC''', '''LSTMOC''', '''biLSTMOC''', '''WT-CNNOC'' '};
            app.ModelListDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelListDropDownValueChanged, true);
            app.ModelListDropDown.Value = '''TCNOC''';
            app.DoubleVerificationLabel = uilabel(app.LeftPanel);
            app.DoubleVerificationLabel.Text = {'Double '; 'Verification'};
            app.DoubleVerificationListBox = uilistbox(app.LeftPanel);
            app.DoubleVerificationListBox.Items = {'1', '2', '3', '4', '5'};
            app.DoubleVerificationListBox.ValueChangedFcn = createCallbackFcn(app, @DoubleVerificationListBoxValueChanged, true);
            app.DoubleVerificationListBox.Value = '3';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'time')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simpleDisplay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end